classdef app_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SSBv10wwwlhaotopUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Panel_4                   matlab.ui.container.Panel
        Switch                    matlab.ui.control.Switch
        Label_3                   matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        Label_7                   matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        Label_6                   matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        Panel_5                   matlab.ui.container.Panel
        DropDown_4                matlab.ui.control.DropDown
        Label_8                   matlab.ui.control.Label
        DropDown_3                matlab.ui.control.DropDown
        Label_5                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        simulinkButton_2          matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        simulinkButton            matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        DropDown_2                matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        Tab_2                     matlab.ui.container.Tab
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        Tab_3                     matlab.ui.container.Tab
        Hyperlink                 matlab.ui.control.Hyperlink
        TurnCoordinator           Aero.ui.control.TurnCoordinator
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function results = pgmod_filter(app)
            %DSB design
            Fs=1024;%采样频率
            t=0:1/Fs:2;%信号长度
            Am=2;%基带信号幅值
            Ac=2;%载波信号幅值
            fm=2;%基带信号频率
            fc=20;%载波信号频率
            wm=2*pi*fm;%基带信号模拟角频率
            wc=2*pi*fc;%载波信号模拟角频率
            Sm=Am*sin(wm*t);%基带信号
            Sc=Ac*sin(wc*t);%载波信号
            SDSB=Sm.*Sc;%DSB已调信号
            spec_SDSB=fft(SDSB,2*Fs);%对DSB信号做2048点FFT，做频谱分析
            mag_SDSB=abs(spec_SDSB);%计算DSB频响幅值
            fdsb=(0:2*Fs-1)./2;%真实频率
            if app.Switch.Value=="On"
            SDSB = awgn(SDSB,-app.Slider.Value);
            end


            %filter design
            % N=7;%椭圆滤波器的阶数
            N=uint8(app.Slider_2.Value);
            [BL,AL]=ellip(N,0.1,80,(fc-fm)/(Fs/2),'low');%设计椭圆低通滤波器
            %其中通带最大衰减为0.1，阻带最小衰减为80，通带边界频率为20-2=18Hz
            
            
            %SSB design
            LSSB=filter(BL,AL,SDSB);%DSB经过Ellips LPF后取得下边带
            spec_LSSB=fft(LSSB,2*Fs);%对LSSB信号做2048点FFT
            mag_LSSB=abs(spec_LSSB);%计算LSSB频响幅值
            fssb=(0:2*Fs-1)./2;

            %figure(1);
            %plot(Sm);grid on;%显示基带信号
            %title('Base Sign');
            %set(gca,'xlim',[0,1000]);
            
            %figure(2);
            %plot(Sc);grid on;%显示载波信号
            %title('Carry Sign');
            %set(gca,'xlim',[0,1000]);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes,SDSB,"b"); 
            title(app.UIAxes,'DSB Wave');
            set(app.UIAxes,'xlim',[0,1000]);
            plot(app.UIAxes_2,LSSB,"b");    
            title(app.UIAxes_2,'LSSB Wave');
            set(app.UIAxes_2,'xlim',[0,1000]);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes_3,fdsb,mag_SDSB,"b");
            title(app.UIAxes_3,'DSB Spectrum')
            set(app.UIAxes_3,'xlim',[10,30]);
            plot(app.UIAxes_4,fssb,mag_LSSB,"b");    
            title(app.UIAxes_4,'LSSB Spectrum')
            set(app.UIAxes_4,'xlim',[10,30]);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           end
        
        function results = pgmod_phase(app)
            ts=0.0025;                      %信号抽样时间间隔
            t=0:ts:10-ts;                   %时间向量
            fs=1/ts;                        %抽样频率
            rng(123); %随机数种子为123
            msg=randi([-3,3],100,1);  %生成消息序列,随机数种子为123
            msg1=msg*ones(1,fs/10);         %扩展成取样信号形式
            msg2=reshape(msg1.',1,length(t));
            Pm=fft(msg2)/fs;                %求消息信号的频谱
            fc=100;                         %载波频率
            s1=0.5*msg2.*cos(2*pi*fc*t);    %USSB信号的同相分量
            hmsg=imag(hilbert(msg2));       %消息信号的Hilbert变换
            s2=0.5*hmsg.*sin(2*pi*fc*t);    %USSB信号的正交分量
            Sussb=s1-s2;                    %完整的USSB信号
            Pussb=fft(Sussb)/fs;            %USSB信号频谱

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes,msg1,"b")
            title(app.UIAxes,'消息信号时域');
            set(app.UIAxes,'xlim',[0,100]);
            plot(app.UIAxes_3,fftshift(abs(Pm)),"b")       %画出消息信号频谱
            title(app.UIAxes_3,'消息信号频谱');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes_2,Sussb,"b")
            title(app.UIAxes_2,'LSSB信号时域')
            plot(app.UIAxes_4,fftshift(abs(Pussb)),"b")     %画出已调信号频谱
            title(app.UIAxes_4,'LSSB信号频谱')
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        function results = pgenmod_coherent(app)

            %DSB design
            Fs=1024;%采样频率
            t=0:1/Fs:2;%信号长度
            Am=2;%基带信号幅值
            Ac=2;%载波信号幅值
            fm=2;%基带信号频率
            fc=20;%载波信号频率
            wm=2*pi*fm;%基带信号模拟角频率
            wc=2*pi*fc;%载波信号模拟角频率
            Sm=Am*sin(wm*t);%基带信号
            Sc=Ac*sin(wc*t);%载波信号
            SDSB=Sm.*Sc;%DSB已调信号
            if app.Switch.Value=="On"
            SDSB = awgn(SDSB,-app.Slider.Value);
            end

            %filter design
            N=uint8(app.Slider_2.Value);
            [BL,AL]=ellip(N,0.1,80,(fc-fm)/(Fs/2),'low');%设计椭圆低通滤波器
            %其中通带最大衰减为0.1，阻带最小衰减为80，通带边界频率为20-2=18Hz
            
            %SSB design
            LSSB=filter(BL,AL,SDSB);%DSB经过Ellips LPF后取得下边带

            %Demodulation SSB
            coherent_carrier=Sc;%相干载波与输入载波一致
            mul_LSSB_cc=coherent_carrier.*LSSB;%相干载波与下边带相乘
            spec_LSSB_cc=fft(mul_LSSB_cc,2*Fs);%对相干载波与下边带相乘后的信号做FFT
            mag_LSSB_cc=abs(spec_LSSB_cc);%求频响幅度
            fdssb=(0:2*Fs-1)./2;%真实频率

            %pass the LPF
            LPF_LSSB=filter(BL,AL,mul_LSSB_cc);
            %把相干载波与下边带相乘后的信号通过LPF
            spec_LPF_LSSB=fft(LPF_LSSB,2*Fs);
            %对相干载波与下边带相乘后信号经过LPF后的信号做FFT
            mag_LPF_LSSB=abs(spec_LPF_LSSB);%求频响幅度
    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes,mul_LSSB_cc,"b")
            title(app.UIAxes,'相干载波相乘');
            set(app.UIAxes,'xlim',[500,2000]);
            plot(app.UIAxes_2,LPF_LSSB,"b")     
            title(app.UIAxes_2,'通过滤波器');
            set(app.UIAxes_2,'xlim',[500,2000]);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            plot(app.UIAxes_3,fdssb,mag_LSSB_cc,"b")
            title(app.UIAxes_3,'相干载波相乘')
            set(app.UIAxes_3,'xlim',[0,50]);
            plot(app.UIAxes_4,fdssb,mag_LPF_LSSB,"b")    
            title(app.UIAxes_4,'通过滤波器');
            set(app.UIAxes_4,'xlim',[0,50]);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              value = app.DropDown.Value;
                switch value
                case '滤波法'
                    pgmod_filter(app);
                case '相移法'
                    pgmod_phase(app);
                end
        end

        % Button pushed function: simulinkButton
        function simulinkButtonPushed(app, event)
                value = app.DropDown.Value;
                switch value
                case '滤波法'
                    open_system("mod_ft.slx") ;
                case '相移法'
                    open_system("mod_ps.slx");
                end
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function Button_3Pushed(app, event)
            close all ;start_simulink;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            pgenmod_coherent(app);
        end

        % Button pushed function: simulinkButton_2
        function simulinkButton_2Pushed(app, event)
            if app.Switch.Value=="On"
            open_system("enmod_nosie.slx");
            else
            open_system("enmod.slx");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SSBv10wwwlhaotopUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {601, 601};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {284, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SSBv10wwwlhaotopUIFigure = uifigure('Visible', 'off');
            app.SSBv10wwwlhaotopUIFigure.Name = 'SSB v1.0 www.lhao.top';
            app.SSBv10wwwlhaotopUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SSBv10wwwlhaotopUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '调制';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '信号产生';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'滤波法', '相移法'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '滤波法';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '滤波器';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'Butterworth', 'Elliptic'};
            app.DropDown_2.Value = 'Elliptic';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '程序仿真';
            app.simulinkButton = uibutton(app.Panel_2, 'push');
            app.simulinkButton.ButtonPushedFcn = createCallbackFcn(app, @simulinkButtonPushed, true);
            app.simulinkButton.Text = 'simulink仿真';
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_3.Title = '解调';
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '程序仿真';
            app.simulinkButton_2 = uibutton(app.Panel_3, 'push');
            app.simulinkButton_2.ButtonPushedFcn = createCallbackFcn(app, @simulinkButton_2Pushed, true);
            app.simulinkButton_2.Text = 'simulink仿真';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '信号调制解调仿真';
            app.Panel_5 = uipanel(app.Panel);
            app.Label_5 = uilabel(app.Panel_5);
            app.Label_5.Text = '调制方式';
            app.DropDown_3 = uidropdown(app.Panel_5);
            app.DropDown_3.Items = {'SSB'};
            app.DropDown_3.Value = 'SSB';
            app.Label_8 = uilabel(app.Panel_5);
            app.Label_8.Text = '解调方式';
            app.DropDown_4 = uidropdown(app.Panel_5);
            app.DropDown_4.Items = {'相干解调', '包络检波'};
            app.DropDown_4.Value = '相干解调';
            app.Panel_4 = uipanel(app.LeftPanel);
            app.Label_6 = uilabel(app.Panel_4);
            app.Label_6.Text = '噪声等级';
            app.Slider = uislider(app.Panel_4);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 5 10];
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = '滤波阶数';
            app.Slider_2 = uislider(app.Panel_4);
            app.Slider_2.Limits = [0 10];
            app.Slider_2.MajorTicks = [0 5 10];
            app.Slider_2.Value = 7;
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = '噪声';
            app.Switch = uiswitch(app.Panel_4, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '时域';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'F')
            ylabel(app.UIAxes, 'A')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'F')
            ylabel(app.UIAxes_2, 'A')
            zlabel(app.UIAxes_2, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '频域';
            app.UIAxes_3 = uiaxes(app.Tab_2);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'F')
            ylabel(app.UIAxes_3, 'A')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.Tab_2);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'F')
            ylabel(app.UIAxes_4, 'A')
            zlabel(app.UIAxes_4, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '关于我';
            app.TurnCoordinator = uiaeroturn(app.Tab_3);
            app.Hyperlink = uihyperlink(app.Tab_3);
            app.Hyperlink.URL = 'www.lhao.top';
            app.Hyperlink.Text = '开源项目';
            % Show the figure after all components are created
            app.SSBv10wwwlhaotopUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SSBv10wwwlhaotopUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SSBv10wwwlhaotopUIFigure)
        end
    end
end